classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI_BubbleAnalyser              matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ExportdataasMenu                matlab.ui.container.Menu
        csvformatMenu                   matlab.ui.container.Menu
        ExporthistogramasMenu           matlab.ui.container.Menu
        pdfMenu                         matlab.ui.container.Menu
        pngMenu                         matlab.ui.container.Menu
        jpegMenu                        matlab.ui.container.Menu
        tifMenu                         matlab.ui.container.Menu
        emfMenu                         matlab.ui.container.Menu
        epsMenu                         matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        WindowMenu                      matlab.ui.container.Menu
        MinimiseMenu                    matlab.ui.container.Menu
        FullscreenMenu                  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        LicenseMenu                     matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        FolderTab                       matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        ImagepreviewLabel               matlab.ui.control.Label
        b_Tab1Next                      matlab.ui.control.Button
        im_SampleImg                    matlab.ui.control.Image
        t_images                        matlab.ui.control.Table
        DirectoryLocationLabel          matlab.ui.control.Label
        b_FolderSelect                  matlab.ui.control.StateButton
        CalibrationTab                  matlab.ui.container.Tab
        pxmmLabel                       matlab.ui.control.Label
        et_CalibrateManually            matlab.ui.control.NumericEditField
        CalibratemanuallyEditFieldLabel  matlab.ui.control.Label
        PreviewLabel_2                  matlab.ui.control.Label
        PreviewLabel                    matlab.ui.control.Label
        orLabel_3                       matlab.ui.control.Label
        b_BackgroundSelect_3            matlab.ui.control.StateButton
        orLabel_2                       matlab.ui.control.Label
        b_ResolutionSelect_2            matlab.ui.control.StateButton
        ImagenameLabel_2                matlab.ui.control.Label
        l_Tab2Info                      matlab.ui.control.Label
        b_ManualResolution              matlab.ui.control.Button
        et_background                   matlab.ui.control.EditField
        orLabel                         matlab.ui.control.Label
        et_pxresolution                 matlab.ui.control.EditField
        ImagenameLabel                  matlab.ui.control.Label
        Step2BackgroundcorrectionimageLabel  matlab.ui.control.Label
        Step1PixelresolutioncalibrationLabel  matlab.ui.control.Label
        b_BackgroundConfirm             matlab.ui.control.Button
        b_ResolutionConfirm             matlab.ui.control.Button
        im_background                   matlab.ui.control.Image
        im_pxresolution                 matlab.ui.control.Image
        b_Tab2Back                      matlab.ui.control.Button
        b_Tab2Next                      matlab.ui.control.Button
        BubbledetectionandfilteringTab  matlab.ui.container.Tab
        b_next_img                      matlab.ui.control.Button
        b_prev_img                      matlab.ui.control.Button
        Step2Label                      matlab.ui.control.Label
        Step1SelectimageprocessingalgorithmLabel  matlab.ui.control.Label
        dd_algorithmpick                matlab.ui.control.DropDown
        t_paramstruct                   matlab.ui.control.Table
        l_Tab3Info                      matlab.ui.control.Label
        ProcessedimageLabel             matlab.ui.control.Label
        SampleimageLabel                matlab.ui.control.Label
        b_batchprocess                  matlab.ui.control.Button
        rbg_usedefaultparams            matlab.ui.container.ButtonGroup
        ChoosingmyownparametersButton   matlab.ui.control.RadioButton
        UsingdefaultparametersButton    matlab.ui.control.RadioButton
        b_Tab3ProcessSample             matlab.ui.control.Button
        BackButtonSTEP3                 matlab.ui.control.Button
        im_sample                       matlab.ui.control.Image
        im_processed                    matlab.ui.control.Image
        ResultsTab                      matlab.ui.container.Tab
        NumcolumnsEditField             matlab.ui.control.NumericEditField
        NumcolumnsEditFieldLabel        matlab.ui.control.Label
        et_x                            matlab.ui.control.EditField
        LegendoptionsLabel              matlab.ui.control.Label
        OrientationDropDown             matlab.ui.control.DropDown
        OrientationDropDownLabel        matlab.ui.control.Label
        PositionDropDown                matlab.ui.control.DropDown
        PositionLabel                   matlab.ui.control.Label
        secondsLabel                    matlab.ui.control.Label
        ElapsedtimeEditField            matlab.ui.control.NumericEditField
        ElapsedtimeEditFieldLabel       matlab.ui.control.Label
        SavehistogrampnganddatacsvButton  matlab.ui.control.Button
        TosavehistogramgotofilesExporthistogramasChooseformatLabel  matlab.ui.control.Label
        et_yLabel                       matlab.ui.control.Label
        et_xLabel                       matlab.ui.control.Label
        et_xmax                         matlab.ui.control.EditField
        maxEditFieldLabel               matlab.ui.control.Label
        et_xmin                         matlab.ui.control.EditField
        minEditFieldLabel               matlab.ui.control.Label
        XaxislimitsLabel                matlab.ui.control.Label
        DescriptivesizeLabel            matlab.ui.control.Label
        l_results                       matlab.ui.control.Label
        rbg_HistogramBy                 matlab.ui.container.ButtonGroup
        VolumeButton                    matlab.ui.control.RadioButton
        NumberButton                    matlab.ui.control.RadioButton
        ResultsLabel                    matlab.ui.control.Label
        et_y                            matlab.ui.control.EditField
        cb_dxy                          matlab.ui.control.CheckBox
        et_NumberofBins                 matlab.ui.control.EditField
        NumberofbinsEditField_2Label    matlab.ui.control.Label
        ConfigurationgraphLabel         matlab.ui.control.Label
        cb_d32                          matlab.ui.control.CheckBox
        cb_dmean                        matlab.ui.control.CheckBox
        cb_PDF                          matlab.ui.control.CheckBox
        cb_CDF                          matlab.ui.control.CheckBox
        BackButtonSTEP4                 matlab.ui.control.Button
        ax_histogram                    matlab.ui.control.UIAxes
    end

    % Author: Reyes, Francisco; Quintanilla, Paulina; Mesa, Diego
    % email: f.reyes@uq.edu.au,
    % Website: https://gitlab.com/frreyes1/bubble-sizer
    % Copyright Oct-2020;
    %
    %This file is part of Bubble Sizer.
    %
    %    Bubble Sizer is free software: you can redistribute it and/or modify
    %    it under the terms of the GNU General Public License as published by
    %    the Free Software Foundation version 3 only of the License.
    %
    %    Bubble Sizer is distributed in the hope that it will be useful,
    %    but WITHOUT ANY WARRANTY; without even the implied warranty of
    %    MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %    GNU General Public License for more details.
    %
    %    You should have received a copy of the GNU General Public License
    %    along with Bubble Sizer.  If not, see <https://www.gnu.org/licenses/>.
    %
    %------------- BEGIN CODE --------------
    properties (Access = private)
        working_folder_path; % Store user selected folder with the images
        quan_folder; %keep where the app's quantification folder seats
        
        %variables to store the working images
        random_img;
        background_img;
        calibration_img;
        labelled_img;
        
        %variables to control flow of the app
        done_step1 = false;
        done_step2 = false;
        done_step3 = false;
        
        %variables to store configuration and calibration
        px2mm; %store the images' resolution, in # of pixels per mm
        processing_params; % image processing parameters
        
        bubble_diameters; %to store batch processed diameters
        bubble_extrainfo %to store extra shape descriptors 
        
        myTimer;
        
        
    end
    
    
    methods (Access = private)
        
        function param_struct = read_param_file(~,fname)
            param_struct = struct();
            fid = fopen(fname);
            tline = fgetl(fid);
            while ischar(tline)
                if ~isempty(tline)
                    is_comment = strcmp(tline(1),'#');
                    if ~is_comment
                        str_parts = split(tline,', '); %get param name
                        param_struct.values.(str_parts{1}) = str2double(str_parts(2)); %store param value in struct
                        %check param range, if not available then asume [-Inf, +Inf]
                        if length(str_parts)<3
                            value_range = {[-Inf,Inf]}; %if range is not provided, then asume -Inf to Inf continious range
                        else                        
                            range = str_parts{3};
                            switch range(1)
                                case '(' %set of possible values
                                    range_parts = split(range(2:end-1),' ');
                                    value_range = zeros(1:length(range_parts));
                                    for i = 1:length(range_parts)
                                        value_range(i) = str2double(range_parts(i));
                                    end
                                case '{' %discrete values
                                    range_parts = str2double( split(range(2:end-1), ' ') );
                                    value_range = range_parts(1):1:range_parts(2);
                                case '[' %continious range
                                    value_range = {str2double( split( range(2:end-1), ' ') )};  %store as cell, to distinguish from other two options
                                otherwise
                                    error('Unknown range for parameter: %s. Check your .config file',str_parts{1})
                            end
                        end
                        param_struct.ranges.(str_parts{1}) = value_range;
                    end
                end
                tline = fgetl(fid);
            end
            fclose(fid);
        end
        
        function [x, q, Q] = bubble_psd(app)
            %Get configuration from the app
            xbins = str2double(app.et_NumberofBins.Value);
            Bubble_diams = app.bubble_diameters;
            
            %bin data for PDF and then do cumsum for CDF
            [q, edges] = histcounts(Bubble_diams, xbins);
            x = mean([edges(1:end-1); edges(2:end)],1); %histcounts returns edges, calculate the centres
            Q = 100 * cumsum(q)/sum(q); %in percent
            
            %If calculations by volume, then reshape distributions
            selectedButton = app.rbg_HistogramBy.SelectedObject;
            by_volume = contains(selectedButton.Text,'Volume');
            if by_volume
                Vols = x.^3; %volume of categorie's centre
                q =  q.*Vols/sum(Vols); %weighted by volume
                q = 100 *  q/sum(q);
                Q = 100 * cumsum(q)/sum(q);
            end
        end
        
        function write_params(app,dir_path)
            fID = fopen(dir_path + "img_processing_params.csv",'w');
            
            fprintf(fID,"Algorithm,%s\n\n",app.dd_algorithmpick.Value);
            params = app.processing_params.values;
            fields = fieldnames(params);
            fprintf(fID,"Parameter,Value\n");
            for i=1:length(fields)
                fprintf(fID,"%s,%1.5f\n",fields{i},params.(fields{i}));
            end
            fclose(fID);
        end
        
        function plot_distrib(app)
            %Get user config
            selectedButton = app.rbg_HistogramBy.SelectedObject;
            by_volume = contains(selectedButton.Text,'Volume');
            do_CDF = app.cb_CDF.Value;
            do_PDF = app.cb_PDF.Value;
            plot_d32 = app.cb_d32.Value;
            plot_dmean = app.cb_dmean.Value;
            plot_dxy = app.cb_dxy.Value;
            dxy_x = str2double(app.et_x.Value);
            dxy_y = str2double(app.et_y.Value);
            xmin = str2double(app.et_xmin.Value);
            xmax = str2double(app.et_xmax.Value);
            lgd_pos = lower(erase(app.PositionDropDown.Value,' '));
            lgd_orient = lower(erase(app.OrientationDropDown.Value,' '));
            
            %Calculate distribution by # or Volume
            [x,q,Q] = bubble_psd(app);
            
            %Do plot accordingly
            cla(app.ax_histogram,'reset'); %clear axis
            hold(app.ax_histogram, 'on');
            if do_CDF && do_PDF  %if both selected, then do a yy-plot
                % Activate the left side of the axes
                yyaxis(app.ax_histogram,'left');
                bar(app.ax_histogram,x,q,1,'DisplayName','q'); %bar width = 1
                if by_volume
                    ylabel(app.ax_histogram, 'q [%vol]');
                else
                    ylabel(app.ax_histogram, 'q [#]');
                end
                set(app.ax_histogram, 'YColor',[0 0 0]);
                
                % Activate the right side of the axes
                yyaxis(app.ax_histogram,'right');
                dx = (x(2)-x(1))/2;
                plot(app.ax_histogram,x+dx,Q,'MarkerSize',7,'Marker','square','LineWidth',1.5,...
                    'Color',[1 0 0],'DisplayName','Q');
                if by_volume
                    ylabel(app.ax_histogram, 'Q [%vol]');
                else
                    ylabel(app.ax_histogram, 'Q [%]');
                end
                set(app.ax_histogram, 'YColor',[0 0 0]);
            elseif do_CDF && ~do_PDF %do only the CDF
                dx = (x(2)-x(1))/2;
                plot(app.ax_histogram,x+dx,Q,'MarkerSize',7,'Marker','square','LineWidth',1.5,...
                    'Color',[1 0 0],'DisplayName','Q');
                if by_volume
                    ylabel(app.ax_histogram, 'Q [%vol]');
                else
                    ylabel(app.ax_histogram, 'Q [%]');
                end
                set(app.ax_histogram, 'YColor',[0 0 0]);
            elseif ~do_CDF && do_PDF %do only PDF
                bar(app.ax_histogram,x,q,1,'DisplayName','q'); %bar width = 1
                if by_volume
                    ylabel(app.ax_histogram, 'q [%vol]');
                else
                    ylabel(app.ax_histogram, 'q [#]');
                end
                set(app.ax_histogram, 'YColor',[0 0 0]);
            end
            
            %Check if additional descriptos need to be added
            %if the algorithm is Fourier, then don't do extra plots. This
            %method calculates d32 directly.
            app.l_results.Text = "";
            algorithm = app.dd_algorithmpick.Value;
            if strcmp(algorithm,"FourierMethod")
                d = app.bubble_diameters;                
                dmean = mean(d); %average d32 over all images                
                xline(app.ax_histogram,dmean,'-','DisplayName','d32_{mean}',...
                    'Color', [0 0 0]);
                prev_text = app.l_results.Text;
                app.l_results.Text = sprintf("%savg. d32 = %.2f mm\n", prev_text, dmean);
            else
                if plot_d32
                    d = app.bubble_diameters;
                    if by_volume
                        Vols = d.^3; %volume of the bubble
                        d32 = sum(d.^3.*Vols)/sum(d.^2.*Vols);
                    else
                        d32 = sum(d.^3)/sum(d.^2);
                    end
                    xline(app.ax_histogram,d32,'--','DisplayName','d_{32}',...
                        'Color', [0 0 0]);
                    app.l_results.Text = sprintf("d32 = %.2f mm\n", d32);
                end
                if plot_dmean
                    d = app.bubble_diameters;
                    if by_volume
                        Vols = d.^3; %volume of the bubble
                        dmean = sum(d.*Vols)/sum(Vols);
                    else
                        dmean = mean(d);
                    end
                    xline(app.ax_histogram,dmean,'-','DisplayName','d_{mean}',...
                        'Color', [0 0 0]);
                    prev_text = app.l_results.Text;
                    app.l_results.Text = sprintf("%sdmean = %.2f mm\n", prev_text, dmean);
                end
                if plot_dxy
                    d = app.bubble_diameters;
                    if by_volume
                        Vols = d.^3; %volume of the bubble
                        dxy = sum(d.^dxy_x.*Vols)/sum(d.^dxy_y.*Vols);
                    else
                        dxy = sum(d.^dxy_x)/sum(d.^dxy_y);
                    end
                    xline(app.ax_histogram,dxy,'--','DisplayName','d_{xy}',...
                        'Color', [0.2 0.2 0.2]);
                    prev_text = app.l_results.Text;
                    app.l_results.Text = sprintf("%sdxy = %.2f mm\n", prev_text, dxy);
                end
            end
            
            % Set the remaining axes properties
            set(app.ax_histogram,'XGrid','on','XMinorTick','on','YGrid','on');
            xlabel(app.ax_histogram, "Equivalent diameter [mm]");
            if xmax-xmin < 0.01
                xmax = xmax + 0.005;
                xmin = xmin - 0.005;
            end
            xlim(app.ax_histogram,[xmin xmax]);
            if ~strcmp(lgd_pos,"none")
                lgd = legend(app.ax_histogram, 'Location', lgd_pos, ...
                    'Orientation',lgd_orient);
                app.ax_histogram.UserData = lgd;
            end
        end
        
        function SavePlot(app)
            %             Save as png and fig files.
            if ismac || isunix
                dir_path = app.working_folder_path + "working_files/";
            elseif ispc
                dir_path = app.working_folder_path + "working_files\";
            end
            exportgraphics(app.ax_histogram, fullfile(dir_path, "Histogram.png"));
        end
        
        function Timer(app)
            app.ElapsedtimeEditField.Value = toc;            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Check where the app is located to then relatively navigate to
            %our quantification foder and look for available algorithms and
            %their parameters
            
            %make sure we use the correct stroke
            if ismac || isunix
                stroke = "/";
            elseif ispc
                stroke = "\";
            end
            %1) Go to the current folder where the app is
            gui_full_path = mfilename("fullpath");
            %2) From the app go to quantification folder
            path_split = strsplit(gui_full_path,stroke);
            check_remote = extractBetween(gui_full_path,1,3,'Boundaries',"exclusive");
            if check_remote{1} == stroke
                app.quan_folder = join(path_split(1:end-2),filesep); %two folders up
                app.quan_folder = stroke + app.quan_folder{1} + stroke +"quantification" + stroke;
            else
                app.quan_folder = join(path_split(1:end-2),stroke); %two folders up
                app.quan_folder = app.quan_folder{1} + stroke +"quantification" + stroke;
            end
            
            
        end

        % Value changed function: b_FolderSelect
        function b_FolderSelectValueChanged(app, event)
            user_path = uigetdir;        % Calls 'uigetdir' to obtain the directory location from the user
            figure(app.GUI_BubbleAnalyser) %Bring back the app to the front
            if ~user_path
                return
            end
            app.DirectoryLocationLabel.Text = user_path;  % Sets the label text to be the selected path
            
            %Load file names in folder
            folder_files = dir(user_path);
            folder_files = folder_files(~[folder_files.isdir]); %exclude folders
            
            %% Separate image files from whatever else it might be there in the folder
            %figure out the extension if the image files (.jpeg, tiff, png, etc...)
            [~, ~, fextensions] = cellfun(@fileparts, {folder_files.name}, 'UniformOutput', false);
            [s, ~, j] = unique(fextensions);
            img_extension = s{mode(j)};
            %with the extension we can now get the image file list
            if ismac || isunix
                img_files = dir([user_path '/*' img_extension]);
            elseif ispc
                img_files = dir([user_path '\*' img_extension]);
            end
            %img_files = dir([user_path '\*' img_extension]);
            image_names = {img_files(:).name}';         % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            app.t_images.Data = image_names;            % Displays the directory information to the UITable.
            %Display a random image
            if length(img_files)>2
                rnd_idx = 2 + randi(length(img_files)-2); %we assume first two images are the calibration and background image, so we skip them
            else
                rnd_idx = 1; %if only one or two photos available pick the first
            end
            selected_img = fullfile( user_path, image_names{rnd_idx} );
            app.random_img = imread(selected_img);
            app.im_SampleImg.ImageSource = selected_img;
            if ismac || isunix
                app.working_folder_path = [user_path '/'];  %store the user's pick. Add final stroke
            elseif ispc
                app.working_folder_path = [user_path '\'];  %store the user's pick. Add final stroke
            end
            app.b_Tab1Next.Enable = true;               %Enable Tab1's 'Next' button
        end

        % Cell selection callback: t_images
        function t_imagesCellSelection(app, event)
            selection_idx = event.Indices(1); %get row selection index
            image_names = app.t_images.DisplayData;
            selected_img = fullfile( app.working_folder_path, image_names{selection_idx} );
            app.random_img = imread(selected_img);
            app.im_SampleImg.ImageSource = selected_img;
        end

        % Menu selected function: LicenseMenu
        function LicenseMenuSelected(app, event)
            helpdlg({'Bubble Sizer  Copyright (C) 2021'; ' '; ...
                'Bubble Analyser is free software: you can redistribute it and/or modify'; ...
                'it under the terms of the GNU General Public License as published'; ...
                'by the Free Software Foundation version 3 only of the License'}, ...
                'License');
        end

        % Button pushed function: b_Tab1Next
        function b_Tab1NextPushed(app, event)
            app.done_step1 = true;
            %populate Tab2 default images, i.e. first image in folder is
            %backgound and second is resolution calibration
            %Do this only the first time, don't overwrite if user goes back
            %and forth between tabs
            if ~app.done_step2
                image_names = app.t_images.DisplayData;
                background_name = fullfile( app.working_folder_path, image_names{1} );
                app.im_background.ImageSource = background_name;
                app.et_background.Value =  image_names{1};
                
                resolution_img = fullfile( app.working_folder_path, image_names{2} );
                app.im_pxresolution.ImageSource = resolution_img;
                app.et_pxresolution.Value =  image_names{2};
            end
            
            %If the working_files folders doesn't exist, create it
            if ~exist(app.working_folder_path + "working_files"  ,'dir')
                mkdir(app.working_folder_path + "working_files");
            end
            %now change to second tab
            app.TabGroup.SelectedTab = app.CalibrationTab;
        end

        % Callback function
        function WhatisthisMenu_2Selected(app, event)
            helpdlg({'Browse folder where images are located'; ' '; ...
                'This will be your working directory'}, ...
                'Find images');
        end

        % Callback function
        function WhatisthisMenu_3Selected(app, event)
            helpdlg({'Click on file names to preview';' '}, 'Image file list');
        end

        % Button pushed function: b_Tab2Back
        function b_Tab2BackButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FolderTab;
        end

        % Button pushed function: b_ResolutionConfirm
        function b_ResolutionConfirmButtonPushed(app, event)
            app.calibration_img = imread(app.im_pxresolution.ImageSource);
            h = figure('name',"Choose two points separated by 1.0 cm and press Enter");
            [icondata,iconcmap] = imread('Calibration_icon.png');
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            uiwait(msgbox('\fontsize{15} Choose two points separated by 1.0 cm and then press Enter', 'Instructions', 'custom', icondata, iconcmap, CreateStruct));
            warning off
            imshow(app.calibration_img);
            [x, y, ~] = impixel(); %Get two points separated by 1cm
            close(h);
            warning on
            
            %now calculate pixel resolution
            d = sqrt(diff(x)^2 + diff(y)^2); %distance in pixels
            app.px2mm = 10/d; %resolution stored as mm per pixel, so it can be used to convert from px to mm (px2mm)
            
            %Enable background correction, which can be optional (but
            %recommended)
            app.Step2BackgroundcorrectionimageLabel.Enable = true;
            app.et_background.Enable = true;
            app.orLabel_3.Enable=true;
            app.ImagenameLabel_2.Enable = true;
            app.b_BackgroundConfirm.Enable = true;
            app.im_background.Enable = true;
            app.b_BackgroundSelect_3.Enable = true;
            app.l_Tab2Info.Text = sprintf("Image resolution: %1.2f px/mm\n" + ...
                "\t\tChoose a background correction image (optional, but recommended)"+...
                " Press confirm", 1/app.px2mm);
            app.b_Tab2Next.Enable = true;
        end

        % Button pushed function: b_BackgroundConfirm
        function b_BackgroundConfirmButtonPushed(app, event)
            app.background_img = imread(app.im_background.ImageSource);
            app.l_Tab2Info.Text = "Background correction image selected. Press Next.";
            app.b_Tab2Next.Enable = true;
        end

        % Button pushed function: b_Tab2Next
        function b_Tab2NextButtonPushed(app, event)
            app.done_step2 = true;
            app.l_Tab2Info.Text = "Saving current configuration in the working directory...";
            drawnow
            
            %1) Store some calibration parameters in our working_folder
            if ismac || isunix
                dir_path = app.working_folder_path + "working_files/";
            elseif ispc
                dir_path = app.working_folder_path + "working_files\";
            end
            
            fID = fopen(dir_path+"calibration.csv","w");
            fprintf(fID,"Parameter,Value,Units\n");
            fprintf(fID,"image_resolution, %1.5f,px/mm",1/app.px2mm);
            fclose(fID);
            
            % 2) Save copies of the images used for resolution calibration
            % and background correction
            if ~isempty(app.calibration_img)
                imwrite(app.calibration_img,dir_path+"Calibration_image.png");
            end
            if ~isempty(app.background_img)
                imwrite(app.background_img,dir_path+"Background_image.png");
            end
            
            % 3) Populate the next Tab with default values
            app.im_sample.ImageSource  = app.im_SampleImg.ImageSource;
            app.TabGroup.SelectedTab = app.BubbledetectionandfilteringTab;
            value = app.dd_algorithmpick.Value;
            param_file = app.quan_folder + string(value) +".config";
            app.processing_params = read_param_file(app,param_file);
            
            %Only "see" batchproces button but will be enable only once
            %"Confirm parameters" is pressed
            app.b_batchprocess.Enable=false;
            app.b_batchprocess.Visible=true;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Determine which tab was selected
            selectedTab = app.TabGroup.SelectedTab;
            
            %Check if it is appropiate to change tab
            switch selectedTab.Title
                case 'Folder'
                    do_switch = true;
                case 'Calibration'
                    do_switch = app.done_step1;
                case 'Bubble detection and filtering'
                    do_switch = app.done_step2;
                case 'Results'
                    do_switch = app.done_step3;
                otherwise
                    error('Unknown tab: %s.',selectedTab.Title)
            end
            
            %if we can't switch, then do nothing
            if ~do_switch
                app.TabGroup.SelectedTab = event.OldValue;
            end
        end

        % Value changed function: et_pxresolution
        function et_pxresolutionValueChanged(app, event)
            new_img_name = app.et_pxresolution.Value;
            pxresolution_name = fullfile( app.working_folder_path, new_img_name);
            if exist(pxresolution_name,'file')
                app.im_pxresolution.ImageSource = pxresolution_name;
            else
                app.et_pxresolution.Value = event.PreviousValue;
            end
        end

        % Value changed function: et_background
        function et_backgroundValueChanged(app, event)
            new_img_name = app.et_background.Value;
            backgound_name = fullfile( app.working_folder_path, new_img_name);
            if exist(backgound_name,'file')
                app.im_background.ImageSource = backgound_name;
            else
                app.et_background.Value = event.PreviousValue;
            end
        end

        % Button pushed function: BackButtonSTEP3
        function BackButtonSTEP3Pushed(app, event)
            app.TabGroup.SelectedTab = app.CalibrationTab;
        end

        % Button pushed function: b_Tab3ProcessSample
        function b_Tab3ProcessSampleButtonPushed(app, event)
            %Wait bar while process sample image:
            %1) Change button name to "Processing"
            app.b_Tab3ProcessSample.Text = 'Processing...';
            %2) Put text on top of icon
            app.b_Tab3ProcessSample.IconAlignment = 'bottom';
            
            %3) Create waitbar with same color as button
            wbar = permute(repmat(app.b_Tab3ProcessSample.BackgroundColor,15,1,200),[1,3,2]);
            wbar([1,end],:,:) = 0; % Black frame around waitbar
            wbar(:,[1,end],:) = 0; % Black frame around waitbar
            wbar_color = [237, 164, 60]/255; %scaled RGB of the bar
            
            %4) Load the empty waitbar to the button
            app.b_Tab3ProcessSample.Icon = wbar;
            drawnow
            
            %5) prepare params for function
            %Check if reading from defaults or from table
            selectedButton = app.rbg_usedefaultparams.SelectedObject;
            use_default = contains(selectedButton.Text,'default');
            if use_default
                params = app.processing_params.values;
            else
                data = app.t_paramstruct.Data;
                params = cell2struct(data(:,2),data(:,1),1);
            end
            
            %6) Loop through the images in the selected folder
            sample_img = app.random_img;
            params.background_img = app.background_img;
            params.px2mm = app.px2mm;
            params.do_batch = false;
            old_dir = pwd();
            cd(app.quan_folder);
            
            algorithm = app.dd_algorithmpick.Value;
            [~, Label_img, ~] = feval(algorithm,sample_img,params);
            currentProg = min(round((size(wbar,2)-2)*(1)),size(wbar,2)-2);
            app.b_Tab3ProcessSample.Icon(2:end-1, 2:currentProg+1, 1) = wbar_color(1);
            app.b_Tab3ProcessSample.Icon(2:end-1, 2:currentProg+1, 2) = wbar_color(2);
            app.b_Tab3ProcessSample.Icon(2:end-1, 2:currentProg+1, 3) = wbar_color(3);
            drawnow;
            
            cd(old_dir);
            %7) update processed image in gui
            app.labelled_img = labeloverlay(app.random_img, Label_img);
            app.im_processed.ImageSource = app.labelled_img;
            
            %8) remove waitbar
            app.b_Tab3ProcessSample.Icon = '';
            
            %9) Change button name
            app.b_Tab3ProcessSample.Enable = true;
            app.b_Tab3ProcessSample.Text = 'Try new parameters';
            %Chose second option
            
            app.l_Tab3Info.Text = {'Opt.1: Press the "Batch process image" button to process all the images in the folder';'Opt.2: Change parameters accordingly by selecting the option "Choose my own parameters" and press "Try new parameters"'};
            app.l_Tab3Info.WordWrap = 'on';
            app.l_Tab3Info.HorizontalAlignment = 'left';
            app.b_batchprocess.Enable = true;
            
        end

        % Drop down opening function: dd_algorithmpick
        function dd_algorithmpickDropDownOpening(app, event)
            %1) once in the quantification folder, look for .m files
            quan_files = app.quan_folder + "*.m";
            
            %2) Update the drop-down menu with available algorithms
            algorithms = {dir(quan_files).name}; %get names only as cell
            algorithms = cellfun(@(x) x(1:end-2),algorithms,'UniformOutput',false); %remove extension from name
            app.dd_algorithmpick.Items = algorithms;
            
            %3) Load the default paraeters from the first algorithm in the
            %list
            param_file = app.quan_folder + algorithms{1} +".config";
            app.processing_params = read_param_file(app,param_file);
        end

        % Value changed function: dd_algorithmpick
        function dd_algorithmpickValueChanged(app, event)
            value = app.dd_algorithmpick.Value;
            param_file = app.quan_folder + string(value) +".config";
            app.processing_params = read_param_file(app,param_file);
            if strcmp(value,'Default')                
                app.t_paramstruct.Tooltip = {'Limits:',...
                                    'Morphological_element_size: [3,10]',...
                                    'Connectivity: use 4 or 8',...
                                    'Marker_size: [2,30]',...
                                    'resample [0.1,1]',...
                                    'Max_Eccentricity: [0.1,1]',...
                                    'Min_Solidity: [0.1,1]',...
                                    'min_size (in mm); [0,50]'};
            else
                app.t_paramstruct.Tooltip = "Look for " + string(value) +".config file for help";
            end
            rbg_usedefaultparamsSelectionChanged(app,event)
        end

        % Selection changed function: rbg_usedefaultparams
        function rbg_usedefaultparamsSelectionChanged(app, event)
            app.b_batchprocess.Enable=false;
            selectedButton = app.rbg_usedefaultparams.SelectedObject;
            use_default = contains(selectedButton.Text,'default');
            
            if use_default
                app.t_paramstruct.Visible = 0;
                app.t_paramstruct.Enable = 'off';
            else
                app.t_paramstruct.Visible = 1;
                app.t_paramstruct.Enable = 'on';
                param_names = fieldnames(app.processing_params.values);
                param_values = struct2cell(app.processing_params.values);
                data = [param_names param_values];
                app.t_paramstruct.Data = data;
            end
            app.b_batchprocess.Enable=true;
        end

        % Button pushed function: b_batchprocess
        function b_batchprocessButtonPushed(app, event)
            %Progress bar based on Adam Danz contibution:
            %https://www.mathworks.com/matlabcentral/discussions/highlights/132277-new-in-r2020a-app-button-animation-truecolor-images
            tic
            %1) Change button name to "Processing"
            app.ElapsedtimeEditField.Enable = true;
            app.ElapsedtimeEditField.Visible = true;
            app.b_batchprocess.Text = 'Processing...';
            app.l_Tab3Info.Text = {'Processing all images...';'This may take a while.'};
            app.l_Tab3Info.HorizontalAlignment = 'center';
            
            %2) Put text on top of icon
            app.b_batchprocess.IconAlignment = 'bottom';
            
            %3) Create waitbar with same color as button
            wbar = permute(repmat(app.b_batchprocess.BackgroundColor,15,1,200),[1,3,2]);
            wbar([1,end],:,:) = 0; % Black frame around waitbar
            wbar(:,[1,end],:) = 0; % Black frame around waitbar
            wbar_color = [237, 164, 60]/255; %scaled RGB of the bar
            
            %4) Load the empty waitbar to the button
            app.b_batchprocess.Icon = wbar;
            drawnow
            
            %5) Loop through the images in the selected folder
            selectedButton = app.rbg_usedefaultparams.SelectedObject;
            use_default = contains(selectedButton.Text,'default');
            if use_default
                params = app.processing_params.values;
            else
                data = app.t_paramstruct.Data;
                params = cell2struct(data(:,2),data(:,1),1);
            end
            %add extra ones coming from the app
            params.background_img = app.background_img;
            params.px2mm = app.px2mm;
            params.do_batch = true;
            
            N_images = length(app.t_images.Data);
            image_names = app.t_images.Data;
            old_dir = pwd();
            cd(app.quan_folder);
            has_background = ~isempty(app.background_img);
            D = [];
            more_info = [];
            for i = 1:N_images
                is_resol_img = strcmp(app.et_pxresolution.Value,image_names{i});
                is_bkgnd_img = strcmp(app.et_background.Value,image_names{i});
                if is_resol_img || (is_bkgnd_img&&has_background)
                    continue
                end
                selected_img = fullfile( app.working_folder_path, image_names{i} );
                I = imread(selected_img);
                
                %use our processing algorithm
                algorithm = app.dd_algorithmpick.Value;
                [img_diameters, ~, more_info_struct] = feval(algorithm,I,params);
                D = [D; img_diameters]; %Maybe think of pre-allocating a huge array rather than grow it
                if ~isempty(more_info_struct)
                    temp_cell = struct2cell(more_info_struct);
                    more_info = [more_info; [temp_cell{:}]];
                end
                
                currentProg = min(round((size(wbar,2)-2)*(i/N_images)),size(wbar,2)-2);
                app.b_batchprocess.Icon(2:end-1, 2:currentProg+1, 1) = wbar_color(1);
                app.b_batchprocess.Icon(2:end-1, 2:currentProg+1, 2) = wbar_color(2);
                app.b_batchprocess.Icon(2:end-1, 2:currentProg+1, 3) = wbar_color(3);
                drawnow;
            end
            cd(old_dir);
            app.bubble_diameters = D;
            if ~isempty(more_info_struct)
                fields = fieldnames(more_info_struct);
                for k=1:length(fields)
                    aux.(fields{k}) = more_info(:,k);            
                end
            else
               aux = struct(); 
            end
            app.bubble_extrainfo = aux;
            app.done_step3 = true;
            
            %6) remove waitbar
            app.b_batchprocess.Icon = '';
            
            
            %7) Change button name
            app.b_batchprocess.Text = 'Batch process images';
            app.l_Tab3Info.Text = "Please wait...";
            app.l_Tab3Info.HorizontalAlignment='center';
            
            %Change to results without pressing "next":
            if ismac || isunix
                dir_path = app.working_folder_path + "working_files/";
            elseif ispc
                dir_path = app.working_folder_path + "working_files\";
            end
            write_params(app, dir_path);
            
            pause (1)
            app.ElapsedtimeEditField.Value = toc;
            
            %plot results
            app.et_xmin.Value = num2str(min(app.bubble_diameters),"%.2f");
            app.et_xmax.Value = num2str(max(app.bubble_diameters),"%.2f");
            plot_distrib(app);
            
            %Go to next tab
            app.TabGroup.SelectedTab = app.ResultsTab;
            app.ExportdataasMenu.Visible = 'on';
            app.ExporthistogramasMenu.Visible = 'on';
            app.ExportdataasMenu.Enable = 'on';
            app.ExporthistogramasMenu.Enable = 'on';
        end

        % Button pushed function: BackButtonSTEP4
        function BackButtonSTEP4Pushed(app, event)
            app.TabGroup.SelectedTab = app.BubbledetectionandfilteringTab;
            app.ExportdataasMenu.Visible = 'on';
            app.ExporthistogramasMenu.Visible = 'on';
            app.ExportdataasMenu.Enable = 'on';
            app.ExporthistogramasMenu.Enable = 'on';
            app.b_batchprocess.Enable = 'off';
            
            % Change button name
            app.b_Tab3ProcessSample.Text = 'Confirm parameters';
            
            %Change text
            app.l_Tab3Info.Text = {'Change parameters accordingly and press "Confirm parameters"'};
            app.l_Tab3Info.WordWrap = 'on';
            app.l_Tab3Info.HorizontalAlignment = 'center';
                        
            %Select parameters again            
            selectedButton = app.rbg_usedefaultparams.SelectedObject;
            use_default = contains(selectedButton.Text,'default');
            
            if use_default
                app.t_paramstruct.Visible = 0;
                app.t_paramstruct.Enable = 'off';
            else
                app.t_paramstruct.Visible = 1;
                app.t_paramstruct.Enable = 'on';
                param_names = fieldnames(app.processing_params.values);
                param_values = struct2cell(app.processing_params.values);
                data = [param_names param_values];
                app.t_paramstruct.Data = data;
            end
            profile off
        end

        % Value changed function: b_ResolutionSelect_2
        function b_ResolutionSelect_2ValueChanged(app, event)
            [selected_img, directory_name] = uigetfile('*.*');    %Select image used for calibration
            figure(app.GUI_BubbleAnalyser) %Bring back the app to the front
            if ~selected_img
                return %If the user clicks Cancel, then just exit the function
            end
            resolution_img = fullfile( directory_name, selected_img );
            app.im_pxresolution.ImageSource = resolution_img;
            %app.et_pxresolution.Value =  selected_img;
            
            app.calibration_img = imread(app.im_pxresolution.ImageSource);%(app.im_pxresolution.ImageSource);
            h = figure('name',"Choose two points separated by 1.0 cm and press Enter");
            [icondata,iconcmap] = imread('Calibration_icon.png');
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            uiwait(msgbox('\fontsize{15} Click two points separated by 1.0 cm and then press Enter', 'Instructions', 'custom', icondata, iconcmap, CreateStruct));
            
            warning off
            imshow(app.calibration_img);
            [x, y, ~] = impixel(); %Get two points separated by 1cm
            close(h);
            warning on
            
            %now calculate pixel resolution
            d = sqrt(diff(x)^2 + diff(y)^2);
            app.px2mm = 10/d; %resolution stored as number of pixels in 10mm
            
            %Enable background correction, which can be optional (but
            %recommended)
            app.Step2BackgroundcorrectionimageLabel.Enable = true;
            app.et_background.Enable = true;
            app.ImagenameLabel_2.Enable = true;
            app.orLabel_3.Enable=true;
            app.b_BackgroundConfirm.Enable = true;
            app.im_background.Enable = true;
            app.b_BackgroundSelect_3.Enable = true;
            app.orLabel_3.Enable = true;
            app.l_Tab2Info.Text = sprintf("Image resolution: %1.2f px/mm\n" + ...
                "\t\tChoose a background correction image (optional, but recommended)"+...
                " Press confirm", 1/app.px2mm);
            app.b_Tab2Next.Enable = true;
        end

        % Value changed function: b_BackgroundSelect_3
        function b_BackgroundSelect_3ValueChanged(app, event)
            [selected_img, directory_path] = uigetfile('*.*');    %Select imagen for background
            figure(app.GUI_BubbleAnalyser) %Bring back the app to the front
            if ~selected_img
                return %If the user clicks Cancel, then just exit the function
            end
            background_name = fullfile( directory_path, selected_img );
            app.im_background.ImageSource = background_name;
            app.et_background.Value =  selected_img;
        end

        % Selection changed function: rbg_HistogramBy
        function rbg_HistogramBySelectionChanged(app, event)
            plot_distrib(app);
        end

        % Value changed function: cb_PDF
        function cb_PDFValueChanged(app, event)
            plot_distrib(app);
        end

        % Value changed function: cb_CDF
        function cb_CDFValueChanged(app, event)
            plot_distrib(app);
        end

        % Value changed function: et_NumberofBins
        function et_NumberofBinsValueChanged(app, event)
            plot_distrib(app);
        end

        % Value changed function: cb_d32
        function cb_d32ValueChanged(app, event)
            plot_distrib(app);
        end

        % Value changed function: cb_dmean
        function cb_dmeanValueChanged(app, event)
            plot_distrib(app);
        end

        % Value changed function: cb_dxy
        function cb_dxyValueChanged(app, event)
            if app.cb_dxy.Value
                app.et_x.Visible = 'on';
                app.et_xLabel.Visible = 'on';
                app.et_y.Visible = 'on';
                app.et_yLabel.Visible = 'on';
            else
                app.et_x.Visible = 'off';
                app.et_xLabel.Visible = 'off';
                app.et_y.Visible = 'off';
                app.et_yLabel.Visible = 'off';
            end
            plot_distrib(app);
        end

        % Value changed function: et_xmin
        function et_xminValueChanged(app, event)
            new_value = app.et_xmin.Value;
            if isnan(str2double(new_value))
                app.et_xmin.Value = event.PreviousValue;
            end
            plot_distrib(app);
        end

        % Value changed function: et_xmax
        function et_xmaxValueChanged(app, event)
            new_value = app.et_xmax.Value;
            if isnan(str2double(new_value))
                app.et_xmax.Value = event.PreviousValue;
            end
            plot_distrib(app);
        end

        % Callback function
        function et_xValueChanged(app, event)
            new_value = app.et_x.Value;
            if isnan(str2double(new_value))
                app.et_xmax.Value = event.PreviousValue;
            end
            plot_distrib(app);
        end

        % Value changed function: et_y
        function et_yValueChanged(app, event)
            new_value = app.et_y.Value;
            if isnan(str2double(new_value))
                app.et_xmax.Value = event.PreviousValue;
            end
            plot_distrib(app);
        end

        % Menu selected function: pdfMenu
        function pdfMenuSelected(app, event)
            if ismac || isunix
                dir_path = app.working_folder_path + "working_files/";
            elseif ispc
                dir_path = app.working_folder_path + "working_files\";
            end
            exportgraphics(app.ax_histogram, fullfile(dir_path, "Histogram.pdf"));
            uiwait(msgbox('Your histogram has been saved in a subfolder (/working_files) in the selected directory', 'Histogram saved as .pdf'));
            warning off
        end

        % Menu selected function: pngMenu
        function pngMenuSelected(app, event)
            if ismac || isunix
                dir_path = app.working_folder_path + "working_files/";
            elseif ispc
                dir_path = app.working_folder_path + "working_files\";
            end
            exportgraphics(app.ax_histogram, fullfile(dir_path, "Histogram.png"))
            uiwait(msgbox('Your histogram has been saved in a subfolder (/working_files) in the selected directory', 'Histogram saved as .png'));
            warning off
        end

        % Menu selected function: jpegMenu
        function jpegMenuSelected(app, event)
            if ismac || isunix
                dir_path = app.working_folder_path + "working_files/";
            elseif ispc
                dir_path = app.working_folder_path + "working_files\";
            end
            exportgraphics(app.ax_histogram, fullfile(dir_path, "Histogram.jpeg"))
            uiwait(msgbox('Your histogram has been saved in a subfolder (/working_files) in the selected directory', 'Histogram saved as .jpeg'));
            warning off
        end

        % Menu selected function: tifMenu
        function tifMenuSelected(app, event)
            if ismac || isunix
                dir_path = app.working_folder_path + "working_files/";
            elseif ispc
                dir_path = app.working_folder_path + "working_files\";
            end
            exportgraphics(app.ax_histogram, fullfile(dir_path, "Histogram.tif"))
            uiwait(msgbox('Your histogram has been saved in a subfolder (/working_files) in the selected directory', 'Histogram saved as .tif'));
            warning off
        end

        % Menu selected function: emfMenu
        function emfMenuSelected(app, event)
            if ismac || isunix
                dir_path = app.working_folder_path + "working_files/";
            elseif ispc
                dir_path = app.working_folder_path + "working_files\";
            end
            exportgraphics(app.ax_histogram, fullfile(dir_path, "Histogram.emf"))
            uiwait(msgbox('Your histogram has been saved in a subfolder (/working_files) in the selected directory', 'Histogram saved as .emf'));
            warning off
        end

        % Menu selected function: epsMenu
        function epsMenuSelected(app, event)
            if ismac || isunix
                dir_path = app.working_folder_path + "working_files/";
            elseif ispc
                dir_path = app.working_folder_path + "working_files\";
            end
            exportgraphics(app.ax_histogram, fullfile(dir_path, "Histogram.eps"));
            uiwait(msgbox('Your histogram has been saved in a subfolder (/working_files) in the selected directory', 'Histogram saved as .eps'));
            warning off
        end

        % Menu selected function: csvformatMenu
        function csvformatMenuSelected(app, event)
            if ismac || isunix
                dir_path = app.working_folder_path + "working_files/";
            elseif ispc
                dir_path = app.working_folder_path + "working_files\";
            end
            
            %get data
            Bubble_diams = app.bubble_diameters;
            Bubble_areas = pi.*(Bubble_diams/2).^2;
            Bubble_idxs = (1:length(Bubble_diams))';
            Bubble_extrainfo = app.bubble_extrainfo;
            
            %Save bubble quantification data
            header = {'Bubble index', 'Equivalent diameter [mm]', 'Bubble area [mm2]'};
            header = [header,fieldnames(Bubble_extrainfo)'];
            quan_temp = num2cell([Bubble_idxs Bubble_diams Bubble_areas struct2array(Bubble_extrainfo)]);
            quan_values = cat(1,header,quan_temp);
            info_filename = dir_path + "img_processing_results.csv";
            fID = fopen(info_filename,'w');
            for k=1:length(header)
                if k~=length(header)
                    fprintf(fID,'%s,',header{k});
                else
                    fprintf(fID,'%s\n',header{k});
                end
            end
            fclose(fID);
            dlmwrite(info_filename, quan_values(2:end,:),'-append','delimiter',',','roffset',0,'coffset',0,'precision',10);
            uiwait(msgbox('Your data file has been saved in a subfolder (/working_files) in the selected directory', 'Data file saved as .csv'));
            warning off
        end

        % Button pushed function: b_ManualResolution
        function b_ManualResolutionButtonPushed(app, event)
            %now use pixel resolution
            value = app.et_CalibrateManually.Value;
            app.px2mm = 1/value; %resolution stored as mm per pixel, so it can be used to convert from px to mm (px2mm)
            
            %Enable background correction, which can be optional (but
            %recommended)
            app.Step2BackgroundcorrectionimageLabel.Enable = true;
            app.et_background.Enable = true;
            app.ImagenameLabel_2.Enable = true;
            app.b_BackgroundConfirm.Enable = true;
            app.im_background.Enable = true;
            app.orLabel_3.Enable=true;
            app.b_BackgroundSelect_3.Enable = true;
            app.l_Tab2Info.Text = sprintf("Image resolution: %1.2f px/mm\n" + ...
                "\t\tChoose a background correction image (optional, but recommended)"+...
                " Press confirm", 1/app.px2mm);
            app.b_Tab2Next.Enable = true;
        end

        % Cell edit callback: t_paramstruct
        function t_paramstructCellEdit(app, event)
            cell_idx = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;
            
            %load user defined ranges
            ranges = app.processing_params.ranges;
            
            %get variable name and check range
            table_data = app.t_paramstruct.Data;
            var_name = table_data{cell_idx(1),1};
            var_range = ranges.(var_name);
            
            %check if we allow the change
            chg_okay = false;
            if iscell(var_range) %if it is a cell, then we check a continious range
                chg_okay = newData>=var_range{1}(1) & newData<=var_range{1}(2);
            else
                chg_okay = ismember(newData,var_range);
            end
            
            %avoid change if required
            if ~chg_okay
                table_data{cell_idx(1),2} = oldData;
                app.t_paramstruct.Data = table_data;
            end
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            close(app.GUI_BubbleAnalyser);
        end

        % Menu selected function: FullscreenMenu
        function FullscreenMenuSelected(app, event)
            app.GUI_BubbleAnalyser.WindowState = 'maximized';
        end

        % Button pushed function: SavehistogrampnganddatacsvButton
        function SavehistogrampnganddatacsvButtonPushed(app, event)
            if ismac || isunix
                dir_path = app.working_folder_path + "working_files/";
            elseif ispc
                dir_path = app.working_folder_path + "working_files\";
            end
            
            %get data
            Bubble_diams = app.bubble_diameters;
            Bubble_areas = pi.*(Bubble_diams/2).^2;
            Bubble_idxs = (1:length(Bubble_diams))';
            Bubble_extrainfo = app.bubble_extrainfo;
            temp_cell = struct2cell(Bubble_extrainfo);
            
            %Save bubble quantification data
            header = {'Bubble index', 'Equivalent diameter [mm]', 'Bubble area [mm2]'};
            header = [header,fieldnames(Bubble_extrainfo)'];
            quan_temp = num2cell([Bubble_idxs Bubble_diams Bubble_areas [temp_cell{:}]]);
            quan_values = cat(1,header,quan_temp);
            info_filename = dir_path + "img_processing_results.csv";
            fID = fopen(info_filename,'w');
            for k=1:length(header)
                if k~=length(header)
                    fprintf(fID,'%s,',header{k});
                else
                    fprintf(fID,'%s\n',header{k});
                end
            end
            fclose(fID);
            
            dlmwrite(info_filename, quan_values(2:end,:),'-append','delimiter',',','roffset',0,'coffset',0,'precision',10);
            exportgraphics(app.ax_histogram, fullfile(dir_path, "Histogram.png"))
            
        end

        % Button pushed function: b_prev_img
        function b_prev_imgButtonPushed(app, event)
            user_path = app.working_folder_path;
            %make sure we use the correct stroke
            if ismac || isunix
                stroke = "/";
            elseif ispc
                stroke = "\";
            end
            current_img = app.im_sample.ImageSource;
            path_split = strsplit(current_img,stroke);
            current_img = path_split{end};
            img_list = app.t_images.Data;
            curr_idx = find(strcmp(current_img,img_list));
            if curr_idx==1
                prev_idx = length(img_list);
            else
                prev_idx = curr_idx-1;
            end
            selected_img = fullfile( user_path, img_list{prev_idx}  );
            app.random_img = imread(selected_img);
            app.im_sample.ImageSource = selected_img;
            drawnow;
        end

        % Button pushed function: b_next_img
        function b_next_imgButtonPushed(app, event)
            user_path = app.working_folder_path;
            %make sure we use the correct stroke
            if ismac || isunix
                stroke = "/";
            elseif ispc
                stroke = "\";
            end
            current_img = app.im_sample.ImageSource;
            path_split = strsplit(current_img,stroke);
            current_img = path_split{end};
            img_list = app.t_images.Data;
            curr_idx = find(strcmp(current_img,img_list));
            if curr_idx==length(img_list)
                next_idx = 1;
            else
                next_idx = curr_idx+1;
            end
            selected_img = fullfile( user_path, img_list{next_idx}  );
            app.random_img = imread(selected_img);
            app.im_sample.ImageSource = selected_img;
            drawnow;
        end

        % Menu selected function: MinimiseMenu
        function MinimiseMenuSelected(app, event)
            pause(1);
            app.GUI_BubbleAnalyser.WindowState = 'minimized';
        end

        % Value changed function: PositionDropDown
        function PositionDropDownValueChanged(app, event)
            plot_distrib(app);
        end

        % Value changed function: OrientationDropDown
        function OrientationDropDownValueChanged(app, event)
            plot_distrib(app);
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            open("Instructions_best.pdf");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI_BubbleAnalyser = uifigure('Visible', 'off');
            app.GUI_BubbleAnalyser.Name = 'Bubble Analyser';
            app.GUI_BubbleAnalyser.Icon = 'icon.png';
            app.FileMenu = uimenu(app.GUI_BubbleAnalyser);
            app.FileMenu.Text = 'File';
            app.ExportdataasMenu = uimenu(app.FileMenu);
            app.ExportdataasMenu.Enable = 'off';
            app.ExportdataasMenu.Text = 'Export data as';
            app.csvformatMenu = uimenu(app.ExportdataasMenu);
            app.csvformatMenu.MenuSelectedFcn = createCallbackFcn(app, @csvformatMenuSelected, true);
            app.csvformatMenu.Text = '.csv format';
            app.ExporthistogramasMenu = uimenu(app.FileMenu);
            app.ExporthistogramasMenu.Enable = 'off';
            app.ExporthistogramasMenu.Text = 'Export histogram as';
            app.pdfMenu = uimenu(app.ExporthistogramasMenu);
            app.pdfMenu.MenuSelectedFcn = createCallbackFcn(app, @pdfMenuSelected, true);
            app.pdfMenu.Text = '.pdf';
            app.pngMenu = uimenu(app.ExporthistogramasMenu);
            app.pngMenu.MenuSelectedFcn = createCallbackFcn(app, @pngMenuSelected, true);
            app.pngMenu.Text = '.png';
            app.jpegMenu = uimenu(app.ExporthistogramasMenu);
            app.jpegMenu.MenuSelectedFcn = createCallbackFcn(app, @jpegMenuSelected, true);
            app.jpegMenu.Text = '.jpeg';
            app.tifMenu = uimenu(app.ExporthistogramasMenu);
            app.tifMenu.MenuSelectedFcn = createCallbackFcn(app, @tifMenuSelected, true);
            app.tifMenu.Text = '.tif';
            app.emfMenu = uimenu(app.ExporthistogramasMenu);
            app.emfMenu.MenuSelectedFcn = createCallbackFcn(app, @emfMenuSelected, true);
            app.emfMenu.Text = '.emf';
            app.epsMenu = uimenu(app.ExporthistogramasMenu);
            app.epsMenu.MenuSelectedFcn = createCallbackFcn(app, @epsMenuSelected, true);
            app.epsMenu.Text = '.eps';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.WindowMenu = uimenu(app.GUI_BubbleAnalyser);
            app.WindowMenu.Text = 'Window';
            app.MinimiseMenu = uimenu(app.WindowMenu);
            app.MinimiseMenu.MenuSelectedFcn = createCallbackFcn(app, @MinimiseMenuSelected, true);
            app.MinimiseMenu.Text = 'Minimise';
            app.FullscreenMenu = uimenu(app.WindowMenu);
            app.FullscreenMenu.MenuSelectedFcn = createCallbackFcn(app, @FullscreenMenuSelected, true);
            app.FullscreenMenu.Text = 'Full screen';
            app.HelpMenu = uimenu(app.GUI_BubbleAnalyser);
            app.HelpMenu.Text = 'Help';
            app.LicenseMenu = uimenu(app.HelpMenu);
            app.LicenseMenu.MenuSelectedFcn = createCallbackFcn(app, @LicenseMenuSelected, true);
            app.LicenseMenu.Text = 'License';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.TabGroup = uitabgroup(app.GUI_BubbleAnalyser);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FolderTab = uitab(app.TabGroup);
            app.FolderTab.Title = 'Folder';
            app.b_FolderSelect = uibutton(app.FolderTab, 'state');
            app.b_FolderSelect.ValueChangedFcn = createCallbackFcn(app, @b_FolderSelectValueChanged, true);
            app.b_FolderSelect.Tooltip = {'Browse folder where the images are located. This folder must contain all photos. '; 'Recommended: Include also one photo for pixel resolution calibration and one photo for background correction, which will be used in the next step'};
            app.b_FolderSelect.Text = 'Select folder';
            app.DirectoryLocationLabel = uilabel(app.FolderTab);
            app.DirectoryLocationLabel.Tooltip = {'This will be your working directory'};
            app.DirectoryLocationLabel.Text = 'Directory location';
            app.t_images = uitable(app.FolderTab);
            app.t_images.ColumnName = {'Photos from directory contents'};
            app.t_images.RowName = {};
            app.t_images.CellSelectionCallback = createCallbackFcn(app, @t_imagesCellSelection, true);
            app.t_images.Tooltip = {'Select image to preview '};
            app.im_SampleImg = uiimage(app.FolderTab);
            app.im_SampleImg.Tooltip = {'This is an example photo'};
            app.im_SampleImg.ImageSource = 'Bubbles_example.PNG';
            app.b_Tab1Next = uibutton(app.FolderTab, 'push');
            app.b_Tab1Next.ButtonPushedFcn = createCallbackFcn(app, @b_Tab1NextPushed, true);
            app.b_Tab1Next.Enable = 'off';
            app.b_Tab1Next.Text = 'Next >>';
            app.ImagepreviewLabel = uilabel(app.FolderTab);
            app.ImagepreviewLabel.Text = 'Image preview';
            app.Label = uilabel(app.FolderTab);
            app.Label.Text = 'Select a photo of interest for your analysis, it will be used later as an example';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.b_Tab2Next = uibutton(app.CalibrationTab, 'push');
            app.b_Tab2Next.ButtonPushedFcn = createCallbackFcn(app, @b_Tab2NextButtonPushed, true);
            app.b_Tab2Next.Enable = 'off';
            app.b_Tab2Next.Text = 'Next >>';
            app.b_Tab2Back = uibutton(app.CalibrationTab, 'push');
            app.b_Tab2Back.ButtonPushedFcn = createCallbackFcn(app, @b_Tab2BackButtonPushed, true);
            app.b_Tab2Back.Text = '<< Back';
            app.im_pxresolution = uiimage(app.CalibrationTab);
            app.im_pxresolution.Tooltip = {'This is an example photo for pixel resolution calibration'};
            app.im_pxresolution.ImageSource = 'PixelCalibration_example.PNG';
            app.im_background = uiimage(app.CalibrationTab);
            app.im_background.Enable = 'off';
            app.im_background.Tooltip = {'This is an example photo for background correction'};
            app.im_background.ImageSource = 'Background_example.PNG';
            app.b_ResolutionConfirm = uibutton(app.CalibrationTab, 'push');
            app.b_ResolutionConfirm.ButtonPushedFcn = createCallbackFcn(app, @b_ResolutionConfirmButtonPushed, true);
            app.b_ResolutionConfirm.Text = 'Confirm';
            app.b_BackgroundConfirm = uibutton(app.CalibrationTab, 'push');
            app.b_BackgroundConfirm.ButtonPushedFcn = createCallbackFcn(app, @b_BackgroundConfirmButtonPushed, true);
            app.b_BackgroundConfirm.Enable = 'off';
            app.b_BackgroundConfirm.Text = 'Confirm';
            app.Step1PixelresolutioncalibrationLabel = uilabel(app.CalibrationTab);
            app.Step1PixelresolutioncalibrationLabel.Tooltip = {''};
            app.Step1PixelresolutioncalibrationLabel.Text = 'Step 1: Pixel resolution calibration';
            app.Step2BackgroundcorrectionimageLabel = uilabel(app.CalibrationTab);
            app.Step2BackgroundcorrectionimageLabel.Enable = 'off';
            app.Step2BackgroundcorrectionimageLabel.Text = 'Step 2: Background correction image';
            app.ImagenameLabel = uilabel(app.CalibrationTab);
            app.ImagenameLabel.Text = 'Image name';
            app.et_pxresolution = uieditfield(app.CalibrationTab, 'text');
            app.et_pxresolution.ValueChangedFcn = createCallbackFcn(app, @et_pxresolutionValueChanged, true);
            app.et_pxresolution.Tooltip = {'Enter the calibration photo name manually and then press Confirm'};
            app.orLabel = uilabel(app.CalibrationTab);
            app.orLabel.Text = 'or:';
            app.et_background = uieditfield(app.CalibrationTab, 'text');
            app.et_background.ValueChangedFcn = createCallbackFcn(app, @et_backgroundValueChanged, true);
            app.et_background.Enable = 'off';
            app.et_background.Tooltip = {'Enter the background photo name manually and then press Confirm'};
            app.b_ManualResolution = uibutton(app.CalibrationTab, 'push');
            app.b_ManualResolution.ButtonPushedFcn = createCallbackFcn(app, @b_ManualResolutionButtonPushed, true);
            app.b_ManualResolution.Tooltip = {'Enter the resolution calibration manually in px/mm'};
            app.b_ManualResolution.Text = 'Confirm';
            app.l_Tab2Info = uilabel(app.CalibrationTab);
            app.l_Tab2Info.Text = 'Confirm, or modify accordingly, the images for resolution calibration ';
            app.ImagenameLabel_2 = uilabel(app.CalibrationTab);
            app.ImagenameLabel_2.Enable = 'off';
            app.ImagenameLabel_2.Text = 'Image name';
            app.b_ResolutionSelect_2 = uibutton(app.CalibrationTab, 'state');
            app.b_ResolutionSelect_2.ValueChangedFcn = createCallbackFcn(app, @b_ResolutionSelect_2ValueChanged, true);
            app.b_ResolutionSelect_2.Tooltip = {'Select the calibration photo from a different folder'};
            app.b_ResolutionSelect_2.Text = 'Select other image for resolution calibration';
            app.orLabel_2 = uilabel(app.CalibrationTab);
            app.orLabel_2.Text = 'or:';
            app.b_BackgroundSelect_3 = uibutton(app.CalibrationTab, 'state');
            app.b_BackgroundSelect_3.ValueChangedFcn = createCallbackFcn(app, @b_BackgroundSelect_3ValueChanged, true);
            app.b_BackgroundSelect_3.Enable = 'off';
            app.b_BackgroundSelect_3.Tooltip = {'Select the background photo from a folder'};
            app.b_BackgroundSelect_3.Text = 'Select other image for background correction';
            app.orLabel_3 = uilabel(app.CalibrationTab);
            app.orLabel_3.Enable = 'off';
            app.orLabel_3.Text = 'or:';
            app.PreviewLabel = uilabel(app.CalibrationTab);
            app.PreviewLabel.Text = 'Preview';
            app.PreviewLabel_2 = uilabel(app.CalibrationTab);
            app.PreviewLabel_2.Text = 'Preview';
            app.CalibratemanuallyEditFieldLabel = uilabel(app.CalibrationTab);
            app.CalibratemanuallyEditFieldLabel.Text = 'Calibrate manually ';
            app.et_CalibrateManually = uieditfield(app.CalibrationTab, 'numeric');
            app.pxmmLabel = uilabel(app.CalibrationTab);
            app.pxmmLabel.Text = 'px/mm';
            app.BubbledetectionandfilteringTab = uitab(app.TabGroup);
            app.BubbledetectionandfilteringTab.Title = 'Bubble detection and filtering';
            app.im_processed = uiimage(app.BubbledetectionandfilteringTab);
            app.im_processed.Tooltip = {'Press ''Confirm parameters'' button to process the sample image showed on the left.'};
            app.im_processed.ImageSource = 'BatchProcess_example.PNG';
            app.im_sample = uiimage(app.BubbledetectionandfilteringTab);
            app.im_sample.Tooltip = {'This photo will be processed once you press confirm '};
            app.BackButtonSTEP3 = uibutton(app.BubbledetectionandfilteringTab, 'push');
            app.BackButtonSTEP3.ButtonPushedFcn = createCallbackFcn(app, @BackButtonSTEP3Pushed, true);
            app.BackButtonSTEP3.Text = '<< Back';
            app.b_Tab3ProcessSample = uibutton(app.BubbledetectionandfilteringTab, 'push');
            app.b_Tab3ProcessSample.ButtonPushedFcn = createCallbackFcn(app, @b_Tab3ProcessSampleButtonPushed, true);
            app.b_Tab3ProcessSample.Tooltip = {'Click to process the sample image'};
            app.b_Tab3ProcessSample.Text = 'Confirm parameters';
            app.rbg_usedefaultparams = uibuttongroup(app.BubbledetectionandfilteringTab);
            app.rbg_usedefaultparams.SelectionChangedFcn = createCallbackFcn(app, @rbg_usedefaultparamsSelectionChanged, true);
            app.rbg_usedefaultparams.Title = 'Image processing sandbox';
            app.UsingdefaultparametersButton = uiradiobutton(app.rbg_usedefaultparams);
            app.UsingdefaultparametersButton.Tooltip = {'Press ''Confirm parameters'' to process the sample image'};
            app.UsingdefaultparametersButton.Text = 'Using default parameters';
            app.UsingdefaultparametersButton.Value = true;
            app.ChoosingmyownparametersButton = uiradiobutton(app.rbg_usedefaultparams);
            app.ChoosingmyownparametersButton.Text = 'Choosing my own parameters';
            app.b_batchprocess = uibutton(app.BubbledetectionandfilteringTab, 'push');
            app.b_batchprocess.ButtonPushedFcn = createCallbackFcn(app, @b_batchprocessButtonPushed, true);
            app.b_batchprocess.Enable = 'off';
            app.b_batchprocess.Tooltip = {'Click to process all photos contained in the folder '};
            app.b_batchprocess.Text = 'Batch process images';
            app.SampleimageLabel = uilabel(app.BubbledetectionandfilteringTab);
            app.SampleimageLabel.Text = 'Sample image';
            app.ProcessedimageLabel = uilabel(app.BubbledetectionandfilteringTab);
            app.ProcessedimageLabel.Text = 'Processed image';
            app.l_Tab3Info = uilabel(app.BubbledetectionandfilteringTab);
            app.l_Tab3Info.Text = {'Choose an image processing algorithm from the dropdown menu.'; 'Tweak parameters as desired and check results using the ''Confirm parameters'' button'};
            app.t_paramstruct = uitable(app.BubbledetectionandfilteringTab);
            app.t_paramstruct.ColumnName = {'Parameter'; 'Value'};
            app.t_paramstruct.RowName = {};
            app.t_paramstruct.ColumnEditable = [false true];
            app.t_paramstruct.CellEditCallback = createCallbackFcn(app, @t_paramstructCellEdit, true);
            app.t_paramstruct.Tooltip = {'Limits:'; 'Morphological_element_size: [3,10]'; 'Connectivity: use 4 or 8'; 'Neighbourhood_size: [4,8]'; 'Marker_size: [2,30]'; 'resample [0.1,1]'; 'Max_Eccentricity: [0.1,1]'; 'Min_Solidity: [0.1,1]'; 'min_size (in mm); [0,50]'};
            app.t_paramstruct.Enable = 'off';
            app.t_paramstruct.Visible = 'off';
            app.dd_algorithmpick = uidropdown(app.BubbledetectionandfilteringTab);
            app.dd_algorithmpick.Items = {'Default'};
            app.dd_algorithmpick.DropDownOpeningFcn = createCallbackFcn(app, @dd_algorithmpickDropDownOpening, true);
            app.dd_algorithmpick.ValueChangedFcn = createCallbackFcn(app, @dd_algorithmpickValueChanged, true);
            app.dd_algorithmpick.Tooltip = {'Choose an image processing algorithm'};
            app.dd_algorithmpick.Value = 'Default';
            app.Step1SelectimageprocessingalgorithmLabel = uilabel(app.BubbledetectionandfilteringTab);
            app.Step1SelectimageprocessingalgorithmLabel.Text = 'Step 1: Select image processing algorithm';
            app.Step2Label = uilabel(app.BubbledetectionandfilteringTab);
            app.Step2Label.Text = 'Step 2:';
            app.b_prev_img = uibutton(app.BubbledetectionandfilteringTab, 'push');
            app.b_prev_img.ButtonPushedFcn = createCallbackFcn(app, @b_prev_imgButtonPushed, true);
            app.b_prev_img.Text = '< Prev. Img';
            app.b_next_img = uibutton(app.BubbledetectionandfilteringTab, 'push');
            app.b_next_img.ButtonPushedFcn = createCallbackFcn(app, @b_next_imgButtonPushed, true);
            app.b_next_img.Text = 'Next Img >';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ax_histogram = uiaxes(app.ResultsTab);
            title(app.ax_histogram, 'Histogram preview')
            xlabel(app.ax_histogram, 'X')
            ylabel(app.ax_histogram, 'Y')
            zlabel(app.ax_histogram, 'Z')
            app.BackButtonSTEP4 = uibutton(app.ResultsTab, 'push');
            app.BackButtonSTEP4.ButtonPushedFcn = createCallbackFcn(app, @BackButtonSTEP4Pushed, true);
            app.BackButtonSTEP4.Text = '<< Back';
            app.cb_CDF = uicheckbox(app.ResultsTab);
            app.cb_CDF.ValueChangedFcn = createCallbackFcn(app, @cb_CDFValueChanged, true);
            app.cb_CDF.Text = 'CDF';
            app.cb_CDF.Value = true;
            app.cb_PDF = uicheckbox(app.ResultsTab);
            app.cb_PDF.ValueChangedFcn = createCallbackFcn(app, @cb_PDFValueChanged, true);
            app.cb_PDF.Text = 'PDF';
            app.cb_PDF.Value = true;
            app.cb_dmean = uicheckbox(app.ResultsTab);
            app.cb_dmean.ValueChangedFcn = createCallbackFcn(app, @cb_dmeanValueChanged, true);
            app.cb_dmean.Text = 'd mean';
            app.cb_d32 = uicheckbox(app.ResultsTab);
            app.cb_d32.ValueChangedFcn = createCallbackFcn(app, @cb_d32ValueChanged, true);
            app.cb_d32.Text = 'd32';
            app.cb_d32.Value = true;
            app.ConfigurationgraphLabel = uilabel(app.ResultsTab);
            app.ConfigurationgraphLabel.Text = 'Configuration graph';
            app.NumberofbinsEditField_2Label = uilabel(app.ResultsTab);
            app.NumberofbinsEditField_2Label.Text = 'Number of bins';
            app.et_NumberofBins = uieditfield(app.ResultsTab, 'text');
            app.et_NumberofBins.ValueChangedFcn = createCallbackFcn(app, @et_NumberofBinsValueChanged, true);
            app.et_NumberofBins.Value = '15';
            app.cb_dxy = uicheckbox(app.ResultsTab);
            app.cb_dxy.ValueChangedFcn = createCallbackFcn(app, @cb_dxyValueChanged, true);
            app.cb_dxy.Text = 'dxy';
            app.et_y = uieditfield(app.ResultsTab, 'text');
            app.et_y.ValueChangedFcn = createCallbackFcn(app, @et_yValueChanged, true);
            app.et_y.Visible = 'off';
            app.et_y.Value = '4';
            app.ResultsLabel = uilabel(app.ResultsTab);
            app.ResultsLabel.Text = 'Results:';
            app.rbg_HistogramBy = uibuttongroup(app.ResultsTab);
            app.rbg_HistogramBy.SelectionChangedFcn = createCallbackFcn(app, @rbg_HistogramBySelectionChanged, true);
            app.rbg_HistogramBy.Title = 'Histogram by:';
            app.NumberButton = uiradiobutton(app.rbg_HistogramBy);
            app.NumberButton.Text = 'Number';
            app.NumberButton.Value = true;
            app.VolumeButton = uiradiobutton(app.rbg_HistogramBy);
            app.VolumeButton.Text = 'Volume';
            app.l_results = uilabel(app.ResultsTab);
            app.l_results.Text = 'd32 = ';
            app.DescriptivesizeLabel = uilabel(app.ResultsTab);
            app.DescriptivesizeLabel.Text = 'Descriptive size';
            app.XaxislimitsLabel = uilabel(app.ResultsTab);
            app.XaxislimitsLabel.Text = 'X-axis limits';
            app.minEditFieldLabel = uilabel(app.ResultsTab);
            app.minEditFieldLabel.Text = 'min';
            app.et_xmin = uieditfield(app.ResultsTab, 'text');
            app.et_xmin.ValueChangedFcn = createCallbackFcn(app, @et_xminValueChanged, true);
            app.et_xmin.Value = '0';
            app.maxEditFieldLabel = uilabel(app.ResultsTab);
            app.maxEditFieldLabel.Text = 'max';
            app.et_xmax = uieditfield(app.ResultsTab, 'text');
            app.et_xmax.ValueChangedFcn = createCallbackFcn(app, @et_xmaxValueChanged, true);
            app.et_xmax.Value = '1';
            app.et_xLabel = uilabel(app.ResultsTab);
            app.et_xLabel.Visible = 'off';
            app.et_xLabel.Text = 'x';
            app.et_yLabel = uilabel(app.ResultsTab);
            app.et_yLabel.Visible = 'off';
            app.et_yLabel.Text = 'y';
            app.TosavehistogramgotofilesExporthistogramasChooseformatLabel = uilabel(app.ResultsTab);
            app.TosavehistogramgotofilesExporthistogramasChooseformatLabel.Text = 'To save histogram in other formats go to File -> Export histogram as -> Choose format';
            app.SavehistogrampnganddatacsvButton = uibutton(app.ResultsTab, 'push');
            app.SavehistogrampnganddatacsvButton.ButtonPushedFcn = createCallbackFcn(app, @SavehistogrampnganddatacsvButtonPushed, true);
            app.SavehistogrampnganddatacsvButton.Tooltip = {'Save in a subfolder (/working_files) in the selected directory.'};
            app.SavehistogrampnganddatacsvButton.Text = {'Save '; '(histogram.png and data.csv)'};
            app.ElapsedtimeEditFieldLabel = uilabel(app.ResultsTab);
            app.ElapsedtimeEditFieldLabel.Text = 'Elapsed time';
            app.ElapsedtimeEditField = uieditfield(app.ResultsTab, 'numeric');
            app.ElapsedtimeEditField.Editable = 'off';
            app.ElapsedtimeEditField.Tooltip = {'Total time for processing all photos in the folder'};
            app.secondsLabel = uilabel(app.ResultsTab);
            app.secondsLabel.Text = 'seconds';
            app.PositionLabel = uilabel(app.ResultsTab);
            app.PositionLabel.Text = 'Position    ';
            app.PositionDropDown = uidropdown(app.ResultsTab);
            app.PositionDropDown.Items = {'North', 'South', 'East', 'West', 'North East', 'North West', 'South East', 'South West', 'North outside', 'South outside', 'East outside', 'West outside', 'North East outside', 'North West outside', 'South East outside', 'South West outside', 'Best', 'Best outside', 'None'};
            app.PositionDropDown.ValueChangedFcn = createCallbackFcn(app, @PositionDropDownValueChanged, true);
            app.PositionDropDown.Value = 'North East';
            app.OrientationDropDownLabel = uilabel(app.ResultsTab);
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.OrientationDropDown = uidropdown(app.ResultsTab);
            app.OrientationDropDown.Items = {'Vertical', 'Horizontal'};
            app.OrientationDropDown.ValueChangedFcn = createCallbackFcn(app, @OrientationDropDownValueChanged, true);
            app.OrientationDropDown.Value = 'Vertical';
            app.LegendoptionsLabel = uilabel(app.ResultsTab);
            app.LegendoptionsLabel.Text = 'Legend options';
            app.et_x = uieditfield(app.ResultsTab, 'text');
            app.et_x.Visible = 'off';
            app.et_x.Value = '5';
            app.NumcolumnsEditFieldLabel = uilabel(app.ResultsTab);
            app.NumcolumnsEditFieldLabel.Enable = 'off';
            app.NumcolumnsEditFieldLabel.Visible = 'off';
            app.NumcolumnsEditFieldLabel.Text = 'Num columns';
            app.NumcolumnsEditField = uieditfield(app.ResultsTab, 'numeric');
            app.NumcolumnsEditField.Editable = 'off';
            app.NumcolumnsEditField.Enable = 'off';
            app.NumcolumnsEditField.Visible = 'off';
            app.NumcolumnsEditField.Value = 1;
            % Show the figure after all components are created
            app.GUI_BubbleAnalyser.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUI_BubbleAnalyser)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI_BubbleAnalyser)
        end
    end
end